classdef RGB_tissues_analysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        CropButton                matlab.ui.control.Button
        LoadImageButton           matlab.ui.control.Button
        UIAxesOriginal            matlab.ui.control.UIAxes
        CenterPanel               matlab.ui.container.Panel
        InstrukcjaButton          matlab.ui.control.Button
        MaskiLabel                matlab.ui.control.Label
        UsuwanietaLabel           matlab.ui.control.Label
        GreenThresholdSpinner     matlab.ui.control.Spinner
        BackgroundSpinnerLabel    matlab.ui.control.Label
        RemoveBackgroundCheckBox  matlab.ui.control.CheckBox
        ImportdanychLabel         matlab.ui.control.Label
        DeleteMaskButton          matlab.ui.control.StateButton
        MaskDropDown              matlab.ui.control.DropDown
        WybierzmaskDropDownLabel  matlab.ui.control.Label
        SaveMaskButton            matlab.ui.control.Button
        SzybkaanalizaLabel        matlab.ui.control.Label
        SmallStatsButton          matlab.ui.control.Button
        BigStatsButton            matlab.ui.control.Button
        TotalAreaButton           matlab.ui.control.Button
        CountObjectsButton        matlab.ui.control.Button
        UseMaskCheckBox           matlab.ui.control.CheckBox
        ContourSwitch             matlab.ui.control.Switch
        ObrysujkonturLabel_2      matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        BlueMaxSpinner            matlab.ui.control.Spinner
        MaxSpinner_3Label         matlab.ui.control.Label
        BlueMinSpinner            matlab.ui.control.Spinner
        MinSpinner_3Label         matlab.ui.control.Label
        SkadowefiltruRGBLabel     matlab.ui.control.Label
        GreenMaxSpinner           matlab.ui.control.Spinner
        MaxSpinner_2Label         matlab.ui.control.Label
        GreenMinSpinner           matlab.ui.control.Spinner
        MinSpinner_2Label         matlab.ui.control.Label
        RedMaxSpinner             matlab.ui.control.Spinner
        MaxSpinnerLabel           matlab.ui.control.Label
        RedMinSpinner             matlab.ui.control.Spinner
        MinSpinnerLabel           matlab.ui.control.Label
        BlueRangeSlider           matlab.ui.control.RangeSlider
        BlueLabel                 matlab.ui.control.Label
        GreenRangeSlider          matlab.ui.control.RangeSlider
        GreenLabel                matlab.ui.control.Label
        RedRangeSlider            matlab.ui.control.RangeSlider
        RedLabel                  matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        SaveImageButton           matlab.ui.control.Button
        UIAxesProcessed           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
         OriginalImage
         ProcessedImage
         FileName
         BackgroundRemovedImage
         ResultHard
         ResultSoft
         SavedMasks
         MasksFile
         OriginalImageFull
    end
    
    methods (Access = private)

        function img = removeBackground(app, img)

if ~app.RemoveBackgroundCheckBox.Value
    return
end

thr = app.GreenThresholdSpinner.Value / 255;

imgD = im2double(img);

R = imgD(:,:,1);
G = imgD(:,:,2);
B = imgD(:,:,3);


mask_bg = (G > thr);


se = strel('disk',5);
mask_bg = imdilate(mask_bg,se);


mask_bg = imclose(mask_bg,se);
mask_bg = imfill(mask_bg,'holes');

mask_obj = ~mask_bg;

for c = 1:3
    tmp = img(:,:,c);
    tmp(~mask_obj) = 0;
    img(:,:,c) = tmp;
end

end
        
        function updateRGB(app)

if isempty(app.OriginalImage)
    return
end

img = app.OriginalImage;


%if app.RemoveBackgroundCheckBox.Value

    %if isempty(app.BackgroundRemovedImage)
        %app.BackgroundRemovedImage = removeBackground(app, app.OriginalImage);
    %end

    %img = app.BackgroundRemovedImage;
    %imshow(img,'Parent',app.UIAxesOriginal)

%else
    %img = app.OriginalImage;
    %app.BackgroundRemovedImage = []; % reset

    %imshow(img,'Parent',app.UIAxesOriginal)
%end

if app.RemoveBackgroundCheckBox.Value
    img = removeBackground(app, app.OriginalImage);
    imshow(img,'Parent',app.UIAxesOriginal)
else
    img = app.OriginalImage;
    imshow(img,'Parent',app.UIAxesOriginal)
end

R = img(:,:,1);
G = img(:,:,2);
B = img(:,:,3);


rRange = app.RedRangeSlider.Value;
gRange = app.GreenRangeSlider.Value;
bRange = app.BlueRangeSlider.Value;

mask = ...
    R >= rRange(1) & R <= rRange(2) & ...
    G >= gRange(1) & G <= gRange(2) & ...
    B >= bRange(1) & B <= bRange(2);


if app.UseMaskCheckBox.Value && ~isempty(app.SavedMasks)

    name = app.MaskDropDown.Value;

    if ~strcmp(name,'Brak masek')
        idx = find(strcmp({app.SavedMasks.Name}, name),1);
        m = app.SavedMasks(idx);

        maskSaved = ...
            R >= m.R(1) & R <= m.R(2) & ...
            G >= m.G(1) & G <= m.G(2) & ...
            B >= m.B(1) & B <= m.B(2);

        mask = mask & maskSaved;
    end
end


result = img;
result(repmat(~mask,[1 1 3])) = 0;

app.ProcessedImage = result;


updateDisplay(app)


end


function updateDisplay(app)

img = app.ProcessedImage;


if strcmp(app.ContourSwitch.Value,'Tak')
    bw = rgb2gray(img) > 0;
    perim = bwperim(bw);

    for c = 1:3
        ch = img(:,:,c);
        ch(perim) = 255;
        img(:,:,c) = ch;
    end
end

imshow(img,'Parent',app.UIAxesProcessed)

end


function originalContourPixels = getReferenceContourPixels(app)

orig = app.OriginalImage;

if size(orig,3)==3
    gray = rgb2gray(orig);
else
    gray = orig;
end

bw = gray > 0;


[B, ~] = bwboundaries(bw, 'noholes');
filled = false(size(bw));
perim = bwperim(bw);

for k = 1:length(B)
    boundary = B{k};
    maskObj = poly2mask(boundary(:,2), boundary(:,1), size(bw,1), size(bw,2));
    filled = filled | maskObj;
end

filled = filled | perim;
originalContourPixels = sum(filled(:));

end


function stats = detectObjects(app)

img = app.ProcessedImage;

gray = rgb2gray(img);
bw = gray > 0;

% etykietowanie obiektów
cc = bwconncomp(bw);

stats = regionprops(cc, 'Area', 'Perimeter', 'BoundingBox');

end

function drawObjects(app)

stats = detectObjects(app);

img = app.ProcessedImage;

imshow(img,'Parent',app.UIAxesProcessed)
hold(app.UIAxesProcessed,'on')

for i = 1:length(stats)
    rectangle(app.UIAxesProcessed,...
        'Position',stats(i).BoundingBox,...
        'EdgeColor','r','LineWidth',1)
end

hold(app.UIAxesProcessed,'off')

end


function results = computeStats(app)

stats = detectObjects(app);

areas = [stats.Area];

results.count = length(areas);         
results.totalArea = sum(areas);        
results.meanArea = mean(areas);
results.minArea = min(areas);
results.maxArea = max(areas);

end

function updateMaskDropdown(app)

if isempty(app.SavedMasks)
    app.MaskDropDown.Items = {'Brak masek'};
else
    names = {app.SavedMasks.Name};
    app.MaskDropDown.Items = names;
end
end


    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesProcessed.XTick = [];
            app.UIAxesProcessed.YTick = [];


app.MasksFile = fullfile(pwd,'masks.mat');

if isfile(app.MasksFile)
    data = load(app.MasksFile);
    app.SavedMasks = data.SavedMasks;
else
    app.SavedMasks = [];
end

updateMaskDropdown(app)
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
[file,path] = uigetfile({'*.jpg;*.png;*.tif;*.tiff'});


if isequal(file,0)
    return
end

img = imread(fullfile(path,file));


% konwersja do uint8
if isa(img,'uint16')
    img = uint8(double(img) / 65535 * 255);
end


app.FileName = file;

app.OriginalImage = img;
app.OriginalImageFull = img; 
app.ProcessedImage = img;


imshow(img,'Parent',app.UIAxesOriginal)
imshow(img,'Parent',app.UIAxesProcessed)
        end

        % Value changed function: RedRangeSlider
        function RedRangeSliderValueChanged(app, event)
val = app.RedRangeSlider.Value;

app.RedMinSpinner.Value = val(1);
app.RedMaxSpinner.Value = val(2);

updateRGB(app)

        end

        % Value changed function: GreenRangeSlider
        function GreenRangeSliderValueChanged(app, event)
val = app.GreenRangeSlider.Value;

app.GreenMinSpinner.Value = val(1);
app.GreenMaxSpinner.Value = val(2);

updateRGB(app)

        end

        % Value changed function: BlueRangeSlider
        function BlueRangeSliderValueChanged(app, event)
val = app.BlueRangeSlider.Value;

app.BlueMinSpinner.Value = val(1);
app.BlueMaxSpinner.Value = val(2);

updateRGB(app)

        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)

r = app.RedRangeSlider.Value;
g = app.GreenRangeSlider.Value;
b = app.BlueRangeSlider.Value;

if app.RemoveBackgroundCheckBox.Value
    thr = app.GreenThresholdSpinner.Value;
else
    thr = '-1';
end

selectedMask = app.MaskDropDown.Value;

if app.UseMaskCheckBox.Value && ~strcmp(selectedMask,'Brak masek')
    maskTag = ['_MASK-' selectedMask];
else
    maskTag = '';
end

[~,name,~] = fileparts(app.FileName);


newName = sprintf('%s%s_R%.0f-%.0f_G%.0f-%.0f_B%.0f-%.0f_BR%d.png', name, maskTag, r(1),r(2), g(1),g(2), b(1),b(2), thr);
[file,path] = uiputfile({'*.png';'*.jpg';'*.tif';'*.tiff'},'Zapisz jako', newName);

if isequal(file,0)
    return
end

imwrite(app.ProcessedImage, fullfile(path,file));


        end

        % Value changed function: ContourSwitch
        function ContourSwitchValueChanged(app, event)
          
updateRGB(app)
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)


app.OriginalImage = app.OriginalImageFull;
app.ProcessedImage = app.OriginalImageFull;

% suwaki RGB
app.RedRangeSlider.Value = [0 255];
app.GreenRangeSlider.Value = [0 255];
app.BlueRangeSlider.Value = [0 255];

% spinnery RGB
app.RedMinSpinner.Value = 0;
app.RedMaxSpinner.Value = 255;

app.GreenMinSpinner.Value = 0;
app.GreenMaxSpinner.Value = 255;

app.BlueMinSpinner.Value = 0;
app.BlueMaxSpinner.Value = 255;

% switche - analiza
app.ContourSwitch.Value = 'Nie';


% checkbox - biały
app.UseMaskCheckBox.Value = false;
app.GreenThresholdSpinner.Value = 120;


updateRGB(app)

        end

        % Value changed function: RedMinSpinner
        function RedMinSpinnerValueChanged(app, event)
           
minVal = app.RedMinSpinner.Value;
maxVal = app.RedMaxSpinner.Value;

if minVal > maxVal
    minVal = maxVal;
    app.RedMinSpinner.Value = minVal;
end

app.RedRangeSlider.Value = [minVal maxVal];

updateRGB(app)

            
        end

        % Value changed function: RedMaxSpinner
        function RedMaxSpinnerValueChanged(app, event)

minVal = app.RedMinSpinner.Value;
maxVal = app.RedMaxSpinner.Value;

if maxVal < minVal
    maxVal = minVal;
    app.RedMaxSpinner.Value = maxVal;
end

app.RedRangeSlider.Value = [minVal maxVal];

updateRGB(app)
          
        end

        % Value changed function: GreenMinSpinner
        function GreenMinSpinnerValueChanged(app, event)
                      
minVal = app.GreenMinSpinner.Value;
maxVal = app.GreenMaxSpinner.Value;

if minVal > maxVal
    minVal = maxVal;
    app.GreenMinSpinner.Value = minVal;
end

app.GreenRangeSlider.Value = [minVal maxVal];

updateRGB(app)

        end

        % Value changed function: GreenMaxSpinner
        function GreenMaxSpinnerValueChanged(app, event)
minVal = app.GreenMinSpinner.Value;
maxVal = app.GreenMaxSpinner.Value;

if maxVal < minVal
    maxVal = minVal;
    app.GreenMaxSpinner.Value = maxVal;
end

app.GreenRangeSlider.Value = [minVal maxVal];

updateRGB(app)

        end

        % Value changed function: BlueMinSpinner
        function BlueMinSpinnerValueChanged(app, event)
                      
minVal = app.BlueMinSpinner.Value;
maxVal = app.BlueMaxSpinner.Value;

if minVal > maxVal
    minVal = maxVal;
    app.BlueMinSpinner.Value = minVal;
end

app.BlueRangeSlider.Value = [minVal maxVal];

updateRGB(app)
        end

        % Value changed function: BlueMaxSpinner
        function BlueMaxSpinnerValueChanged(app, event)
minVal = app.BlueMinSpinner.Value;
maxVal = app.BlueMaxSpinner.Value;

if maxVal < minVal
    maxVal = minVal;
    app.BlueMaxSpinner.Value = maxVal;
end

app.BlueRangeSlider.Value = [minVal maxVal];

updateRGB(app)

            
        end

        % Value changed function: UseMaskCheckBox
        function UseMaskCheckBoxValueChanged(app, event)
              
       updateRGB(app)

        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)


if app.RemoveBackgroundCheckBox.Value
    img = removeBackground(app, app.OriginalImage);
else
    img = app.OriginalImage;
end

imshow(img, 'Parent', app.UIAxesOriginal)


h = drawpolygon(app.UIAxesOriginal);
uiwait(msgbox('Zakończ rysowanie i kliknij OK'));


vertices = round(h.Position);

mask = poly2mask(vertices(:,1), vertices(:,2), size(img,1), size(img,2));


cropped = img;
if size(img,3) == 3
    for i = 1:3
        channel = cropped(:,:,i);
        channel(~mask) = 0;
        cropped(:,:,i) = channel;
    end
else
    cropped(~mask) = 0;
end


props = regionprops(mask, 'BoundingBox');
bbox = round(props.BoundingBox);
cropped = imcrop(cropped, bbox);


app.OriginalImage = cropped;
app.ProcessedImage = cropped;


imshow(cropped, 'Parent', app.UIAxesOriginal)
imshow(cropped, 'Parent', app.UIAxesProcessed)


        end

        % Button pushed function: CountObjectsButton
        function CountObjectsButtonPushed(app, event)
            stats = detectObjects(app);
            n = length(stats);

            uialert(app.UIFigure, sprintf('Liczba obiektów: %d', n), 'Wynik')

        end

        % Button pushed function: TotalAreaButton
        function TotalAreaButtonPushed(app, event)
            stats = detectObjects(app);
            areas = [stats.Area];

            totalArea = sum(areas);

            uialert(app.UIFigure, sprintf('Suma pól: %.0f px', totalArea), 'Wynik')

        end

        % Button pushed function: BigStatsButton
        function BigStatsButtonPushed(app, event)

stats = detectObjects(app);
areas = [stats.Area];

count = length(areas);
totalArea = sum(areas);

areasFiltered = areas(areas > 5);
filteredCount = length(areasFiltered);
filteredTotalArea = sum(areasFiltered);

img = app.ProcessedImage;

if size(img,3)==3
    gray = rgb2gray(img);
else
    gray = img;
end

analyzedArea = numel(gray);

relativeArea = (totalArea / analyzedArea) * 100;
filteredRelativeArea = (filteredTotalArea / analyzedArea) * 100;

density = count / analyzedArea;


r = app.RedRangeSlider.Value;
g = app.GreenRangeSlider.Value;
b = app.BlueRangeSlider.Value;
thr = app.GreenThresholdSpinner.Value;

maskName = "None";

if app.UseMaskCheckBox.Value && ~strcmp(app.MaskDropDown.Value,'Brak masek')
    idx = find(strcmp({app.SavedMasks.Name}, app.MaskDropDown.Value),1);
    m = app.SavedMasks(idx);

    maskParams = [m.R(1), m.R(2), m.G(1), m.G(2), m.B(1), m.B(2)];
    maskName = m.Name;
else
    maskParams = [NaN NaN NaN NaN NaN NaN];
end


[~, name, ~] = fileparts(app.FileName);
[file,path] = uiputfile('*.xlsx', 'Save Excel', [name '_big_stats.xlsx']);
if isequal(file,0)
    return
end

fullFile = fullfile(path,file);


paramHeaders = {'Image','MaskName','Rmin','Rmax','Gmin','Gmax','Bmin','Bmax','GreenThr','MaskRmin','MaskRmax','MaskGmin','MaskGmax','MaskBmin','MaskBmax'};
paramData = {name, maskName, r(1), r(2), g(1), g(2), b(1), b(2), thr, maskParams(1), maskParams(2), maskParams(3), maskParams(4), maskParams(5), maskParams(6)};

writecell(paramHeaders, fullFile, 'Sheet', 'Parameters', 'Range', 'A1');
writecell(paramData, fullFile, 'Sheet', 'Parameters', 'Range', 'A2');


summaryHeaders = {'Stage','Count','TotalArea','RelArea [%]','Density','Mean','Median','Min','Max'};
summaryBefore = {'Before filtering', count, totalArea, relativeArea, density, mean(areas), median(areas), min(areas), max(areas)};


if isempty(areasFiltered)
    meanF = NaN; medianF = NaN; minF = NaN; maxF = NaN;
else
    meanF = mean(areasFiltered);
    medianF = median(areasFiltered);
    minF = min(areasFiltered);
    maxF = max(areasFiltered);
end

summaryAfter = {
    'After filtering (>5 px)', ...
    filteredCount, ...
    filteredTotalArea, ...
    filteredRelativeArea, ...
    filteredCount / analyzedArea, ... 
    meanF, ...
    medianF, ...
    minF, ...
    maxF
};

writecell(summaryHeaders, fullFile, 'Sheet','Summary','Range','A1');
writecell(summaryBefore, fullFile, 'Sheet','Summary','Range','A2');
writecell(summaryAfter, fullFile, 'Sheet','Summary','Range','A3');


if ~isempty(areas)

    maxA = max(areas);
    edges = 0:5:(ceil(maxA/5)*5);

    counts = histcounts(areas, edges);

    areaSum = zeros(size(counts));
    for i = 1:length(counts)
        idx = areas >= edges(i) & areas < edges(i+1);
        areaSum(i) = sum(areas(idx));
    end

    areaPercent = areaSum / sum(areas) * 100;

    labels = strings(length(counts),1);
    for i = 1:length(counts)
        labels(i) = sprintf('%d-%d', edges(i), edges(i+1));
    end

    histData = [cellstr(labels) num2cell(counts') num2cell(areaPercent')];

    writecell({'Range','Count','Area [%]'}, fullFile, 'Sheet','Histogram','Range','A1');
    writecell(histData, fullFile, 'Sheet','Histogram','Range','A2');

   
    fig1 = figure('Visible','off');
    histogram(areas, 'BinEdges', edges)

    title('Object Area Histogram')
    xlabel('Area (px)')
    ylabel('Count')

    saveas(fig1, fullfile(path, [name '_hist_count.png']))


    fig2 = figure('Visible','off');
    bar(edges(1:end-1), areaPercent)

    title('Area Contribution [%]')
    xlabel('Area range (px)')
    ylabel('Percentage [%]')

    saveas(fig2, fullfile(path, [name '_hist_area.png']))

end

uialert(app.UIFigure,'Dane zapisane','Sukces')
        end

        % Button pushed function: SmallStatsButton
        function SmallStatsButtonPushed(app, event)


stats = detectObjects(app);
areas = [stats.Area];

count = length(areas);
totalArea = sum(areas);

img = app.ProcessedImage;

if size(img,3)==3
    gray = rgb2gray(img);
else
    gray = img;
end


analyzedArea = size(gray,1) * size(gray,2);


    if analyzedArea == 0
        uialert(app.UIFigure,'Brak analizowanego obszaru!','Błąd');
        return
    end

   
    relativeArea = (totalArea / analyzedArea) * 100;
    density = count / analyzedArea;

     
     [~, name, ~] = fileparts(app.FileName);  
   
    [file,path] = uiputfile('*.xlsx', 'Zapisz Excel', [name '_small_stat.xlsx']);
    
    if isequal(file,0)
        return
    end

    fileName = fullfile(path,file);


    headers = {'ImageName','Count','TotalArea','RelArea [%]','Density [1/px]', 'MedianArea', 'MinArea', 'MaxArea'};

    newRow = {
    name,...
    count,...
    totalArea,...
    relativeArea,...
    density,...
    median(areas),...
    min(areas),...
    max(areas)
};


    if isfile(fileName)
        existing = readcell(fileName);
        writecell(newRow, fileName, 'Range', sprintf('A%d', size(existing,1)+1));
    else
        writecell(headers, fileName, 'Range','A1');
        writecell(newRow, fileName, 'Range','A2');
    end

    uialert(app.UIFigure,'Dane zapisane','Sukces')


        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)
prompt = {'Nazwa maski:'};
dlgtitle = 'Zapisz maskę';
answer = inputdlg(prompt, dlgtitle, [1 50]);

if isempty(answer)
    return
end

name = answer{1};

maskStruct.Name = name;
maskStruct.R = app.RedRangeSlider.Value;
maskStruct.G = app.GreenRangeSlider.Value;
maskStruct.B = app.BlueRangeSlider.Value;

if isempty(app.SavedMasks)
    app.SavedMasks = maskStruct;
else
    app.SavedMasks(end+1) = maskStruct;
end

SavedMasks = app.SavedMasks;
save(app.MasksFile, 'SavedMasks')

updateMaskDropdown(app)


        end

        % Value changed function: DeleteMaskButton
        function DeleteMaskButtonValueChanged(app, event)
name = app.MaskDropDown.Value;

if strcmp(name,'Brak masek')
    return
end

idx = find(strcmp({app.SavedMasks.Name}, name),1);
app.SavedMasks(idx) = [];

SavedMasks = app.SavedMasks;
save(app.MasksFile, 'SavedMasks')

updateMaskDropdown(app)

        end

        % Value changed function: RemoveBackgroundCheckBox
        function RemoveBackgroundCheckBoxValueChanged(app, event)
updateRGB(app)       
        end

        % Value changed function: GreenThresholdSpinner
        function GreenThresholdSpinnerValueChanged(app, event)
  updateRGB(app)
            
        end

        % Button pushed function: InstrukcjaButton
        function InstrukcjaButtonPushed(app, event)
  fileName = 'RGB_tissues_analysis_App_Instrukcja.txt';

if ~isfile(fileName)
    uialert(app.UIFigure, 'Nie znaleziono pliku instrukcji!', 'Błąd');
    return
end

txt = fileread(fileName);

fig = uifigure('Name','Instrukcja','Position',[100 100 600 500]);


uitextarea(fig,...
    'Value', splitlines(txt), ...
    'Editable','off', ...
    'Position',[10 10 580 480]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {505, 505, 505};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {505, 505};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {342, '1x', 341};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxesOriginal = uiaxes(app.LeftPanel);
            zlabel(app.UIAxesOriginal, 'Z')
            app.LoadImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Wgraj zdjęcie';
            app.CropButton = uibutton(app.LeftPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Wybierz analizowany obszar';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RedLabel = uilabel(app.CenterPanel);
            app.RedLabel.Text = 'Red';
            app.RedRangeSlider = uislider(app.CenterPanel, 'range');
            app.RedRangeSlider.Limits = [0 255];
            app.RedRangeSlider.ValueChangedFcn = createCallbackFcn(app, @RedRangeSliderValueChanged, true);
            app.RedRangeSlider.Value = [0 255];
            app.GreenLabel = uilabel(app.CenterPanel);
            app.GreenLabel.Text = 'Green';
            app.GreenRangeSlider = uislider(app.CenterPanel, 'range');
            app.GreenRangeSlider.Limits = [0 255];
            app.GreenRangeSlider.ValueChangedFcn = createCallbackFcn(app, @GreenRangeSliderValueChanged, true);
            app.GreenRangeSlider.Value = [0 255];
            app.BlueLabel = uilabel(app.CenterPanel);
            app.BlueLabel.Text = 'Blue';
            app.BlueRangeSlider = uislider(app.CenterPanel, 'range');
            app.BlueRangeSlider.Limits = [0 255];
            app.BlueRangeSlider.ValueChangedFcn = createCallbackFcn(app, @BlueRangeSliderValueChanged, true);
            app.BlueRangeSlider.Value = [0 255];
            app.MinSpinnerLabel = uilabel(app.CenterPanel);
            app.MinSpinnerLabel.Text = 'Min';
            app.RedMinSpinner = uispinner(app.CenterPanel);
            app.RedMinSpinner.Limits = [0 255];
            app.RedMinSpinner.ValueChangedFcn = createCallbackFcn(app, @RedMinSpinnerValueChanged, true);
            app.MaxSpinnerLabel = uilabel(app.CenterPanel);
            app.MaxSpinnerLabel.Text = 'Max';
            app.RedMaxSpinner = uispinner(app.CenterPanel);
            app.RedMaxSpinner.Limits = [0 255];
            app.RedMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @RedMaxSpinnerValueChanged, true);
            app.RedMaxSpinner.Value = 255;
            app.MinSpinner_2Label = uilabel(app.CenterPanel);
            app.MinSpinner_2Label.Text = 'Min';
            app.GreenMinSpinner = uispinner(app.CenterPanel);
            app.GreenMinSpinner.Limits = [0 255];
            app.GreenMinSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenMinSpinnerValueChanged, true);
            app.MaxSpinner_2Label = uilabel(app.CenterPanel);
            app.MaxSpinner_2Label.Text = 'Max';
            app.GreenMaxSpinner = uispinner(app.CenterPanel);
            app.GreenMaxSpinner.Limits = [0 255];
            app.GreenMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenMaxSpinnerValueChanged, true);
            app.GreenMaxSpinner.Value = 255;
            app.SkadowefiltruRGBLabel = uilabel(app.CenterPanel);
            app.SkadowefiltruRGBLabel.Text = 'Składowe filtru RGB';
            app.MinSpinner_3Label = uilabel(app.CenterPanel);
            app.MinSpinner_3Label.Text = 'Min';
            app.BlueMinSpinner = uispinner(app.CenterPanel);
            app.BlueMinSpinner.Limits = [0 255];
            app.BlueMinSpinner.ValueChangedFcn = createCallbackFcn(app, @BlueMinSpinnerValueChanged, true);
            app.MaxSpinner_3Label = uilabel(app.CenterPanel);
            app.MaxSpinner_3Label.Text = 'Max';
            app.BlueMaxSpinner = uispinner(app.CenterPanel);
            app.BlueMaxSpinner.Limits = [0 255];
            app.BlueMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @BlueMaxSpinnerValueChanged, true);
            app.BlueMaxSpinner.Value = 255;
            app.ResetButton = uibutton(app.CenterPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Resetuj';
            app.ObrysujkonturLabel_2 = uilabel(app.CenterPanel);
            app.ObrysujkonturLabel_2.Text = 'Zaznacz obiekty';
            app.ContourSwitch = uiswitch(app.CenterPanel, 'slider');
            app.ContourSwitch.Items = {'Nie', 'Tak'};
            app.ContourSwitch.ValueChangedFcn = createCallbackFcn(app, @ContourSwitchValueChanged, true);
            app.ContourSwitch.Value = 'Nie';
            app.UseMaskCheckBox = uicheckbox(app.CenterPanel);
            app.UseMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseMaskCheckBoxValueChanged, true);
            app.UseMaskCheckBox.Text = 'Pokaż maskę';
            app.CountObjectsButton = uibutton(app.CenterPanel, 'push');
            app.CountObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @CountObjectsButtonPushed, true);
            app.CountObjectsButton.Text = 'Licz obiekty';
            app.TotalAreaButton = uibutton(app.CenterPanel, 'push');
            app.TotalAreaButton.ButtonPushedFcn = createCallbackFcn(app, @TotalAreaButtonPushed, true);
            app.TotalAreaButton.Text = 'Sumuj pole obiektów';
            app.BigStatsButton = uibutton(app.CenterPanel, 'push');
            app.BigStatsButton.ButtonPushedFcn = createCallbackFcn(app, @BigStatsButtonPushed, true);
            app.BigStatsButton.Text = 'Big Data';
            app.SmallStatsButton = uibutton(app.CenterPanel, 'push');
            app.SmallStatsButton.ButtonPushedFcn = createCallbackFcn(app, @SmallStatsButtonPushed, true);
            app.SmallStatsButton.Text = 'Small Data';
            app.SzybkaanalizaLabel = uilabel(app.CenterPanel);
            app.SzybkaanalizaLabel.Text = 'Szybka analiza';
            app.SaveMaskButton = uibutton(app.CenterPanel, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Zapisz maskę';
            app.WybierzmaskDropDownLabel = uilabel(app.CenterPanel);
            app.WybierzmaskDropDownLabel.Text = 'Wybierz maskę';
            app.MaskDropDown = uidropdown(app.CenterPanel);
            app.DeleteMaskButton = uibutton(app.CenterPanel, 'state');
            app.DeleteMaskButton.ValueChangedFcn = createCallbackFcn(app, @DeleteMaskButtonValueChanged, true);
            app.DeleteMaskButton.Text = 'Usuń maskę';
            app.ImportdanychLabel = uilabel(app.CenterPanel);
            app.ImportdanychLabel.Text = 'Import danych';
            app.RemoveBackgroundCheckBox = uicheckbox(app.CenterPanel);
            app.RemoveBackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveBackgroundCheckBoxValueChanged, true);
            app.RemoveBackgroundCheckBox.Text = 'Filtruj tło';
            app.BackgroundSpinnerLabel = uilabel(app.CenterPanel);
            app.BackgroundSpinnerLabel.Text = 'Background';
            app.GreenThresholdSpinner = uispinner(app.CenterPanel);
            app.GreenThresholdSpinner.Step = 0.5;
            app.GreenThresholdSpinner.Limits = [0 255];
            app.GreenThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenThresholdSpinnerValueChanged, true);
            app.GreenThresholdSpinner.Value = 120;
            app.UsuwanietaLabel = uilabel(app.CenterPanel);
            app.UsuwanietaLabel.Text = 'Usuwanie tła';
            app.MaskiLabel = uilabel(app.CenterPanel);
            app.MaskiLabel.Text = 'Maski';
            app.InstrukcjaButton = uibutton(app.CenterPanel, 'push');
            app.InstrukcjaButton.ButtonPushedFcn = createCallbackFcn(app, @InstrukcjaButtonPushed, true);
            app.InstrukcjaButton.Text = 'Instrukcja';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesProcessed = uiaxes(app.RightPanel);
            title(app.UIAxesProcessed, ' ')
            zlabel(app.UIAxesProcessed, 'Z')
            app.SaveImageButton = uibutton(app.RightPanel, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Zapisz zdjęcie';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RGB_tissues_analysis_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end